classdef controlApp < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                    matlab.ui.Figure
        TabGroup                    matlab.ui.container.TabGroup
        MeasuringTab                matlab.ui.container.Tab
        StartStopButton             matlab.ui.control.StateButton
        PlotButton                  matlab.ui.control.Button
        TimestampLabel              matlab.ui.control.Label
        Received_timestampLabel     matlab.ui.control.Label
        SpeedLabel                  matlab.ui.control.Label
        received_speedLabel         matlab.ui.control.Label
        ConnectionsPanel            matlab.ui.container.Panel
        SendPortPanel               matlab.ui.container.Panel
        PortDropDownLabel           matlab.ui.control.Label
        PortDropDown                matlab.ui.control.DropDown
        ConnectButton               matlab.ui.control.StateButton
        ReceivePortPanel            matlab.ui.container.Panel
        PortDropDown_2Label         matlab.ui.control.Label
        PortDropDown_2              matlab.ui.control.DropDown
        ConnectButton_2             matlab.ui.control.StateButton
        ClearConnectionsButton      matlab.ui.control.Button
        Lamp_3                      matlab.ui.control.Lamp
        Lamp_2                      matlab.ui.control.Lamp
        Lamp                        matlab.ui.control.Lamp
        ModeSwitch                  matlab.ui.control.Switch
        StepSignalPanel             matlab.ui.container.Panel
        LabelNumericEditField3      matlab.ui.control.Label
        StepLengthEditField         matlab.ui.control.NumericEditField
        StepSizeEditFieldLabel      matlab.ui.control.Label
        StepSizeEditField           matlab.ui.control.NumericEditField
        UIAxes                      matlab.ui.control.UIAxes
        UIAxes2                     matlab.ui.control.UIAxes
        ThrottleSetEditFieldLabel   matlab.ui.control.Label
        ThrottleSetEditField        matlab.ui.control.NumericEditField
        CurrthrottleEditFieldLabel  matlab.ui.control.Label
        CurrthrottleEditField       matlab.ui.control.NumericEditField
        ThrottleSliderLabel         matlab.ui.control.Label
        ThrottleSlider              matlab.ui.control.Slider
        IdentificationTab           matlab.ui.container.Tab
        ListBoxLabel                matlab.ui.control.Label
        ListBox                     matlab.ui.control.ListBox
        UIAxes3                     matlab.ui.control.UIAxes
        IdentificationButton        matlab.ui.control.Button
        UIAxesSpeedThrottle         matlab.ui.control.UIAxes
        UIAxesPT1                   matlab.ui.control.UIAxes
        UIAxesSchub                 matlab.ui.control.UIAxes
        Label                       matlab.ui.control.Label
        Label_2                     matlab.ui.control.Label
        DeadtimeLabel               matlab.ui.control.Label
        DeadtimeEditField           matlab.ui.control.NumericEditField
        msLabel                     matlab.ui.control.Label
        TauLabel                    matlab.ui.control.Label
        TauEditField                matlab.ui.control.NumericEditField
        msLabel_2                   matlab.ui.control.Label
        ThrustCurveLabel            matlab.ui.control.Label
    end

    
    properties (Access = public)
        myTimer = timer % Description
        arduinoSerial = serial('COM1');
        arduinoSerial_Send = serial('COM1');% Description
        arduinoSerial_Receive = serial('COM1');
        receivedData = []% Description
    end
    
    properties (Access = private)
        Property4 % Description
        setThrottleValue = 0 % Description
        previousThrottleValue = 0;
        myLine % Description
        numOfBytes = 8;  % NUM OF BYTES
        bufferLength = 30;  % 1 buffer length = 1 data Set  % läuft gut  mit 30 ok
        lengthCalcSpeed = 10;
        bufferBytes;
        temp = 0;
        temp2 = 0;
        currentPos = 0;
        varTimestamp = 0;
        arduinoTimestampBegin = 0;
        arduinoTimestampCurrent = 0;
        
        %Calibration (20.12.2017)
        throttleCalibrationMax = 252;
        
        
        %Array to save Data
        %receivedArray = zeros(1000000,8);  % NUM OF BYTES !
        
        myNumber = 0;
        
        %Array to receive
        received = zeros(20,8);
        receivedDataset = 0;
        
        displayLength = 1000;
        time = [];
        speed = 0;
        
        %Step Signal
        stepArrayCounter=0;
        stepArray=[];
        numberOfSteps = 0;
        
        
        %  Timestamp  | Signal Input | Speed  [RPM] |  force  |  current
        savedData = zeros(10000000,5);
        rawData = zeros(10000000,8);
        currentPositionInRecArr = 2;
        posCounter = 2;
        baud = 115200;
        
        % Measurement Archive
        directoryMeasurementName = 'Messungen';
        measureFolder = [];
        
        
        %Identification
        %Speed
        speedArrayStaticMeanAll = [];
        speedArrayStaticMeanMeanAll = [];
        throttleArrayStaticMeanAll = [];
        %Totzeit
        meanDeadTimeUpAll =[];
        meanDeadTimeDownAll = [];
        meanDeadTimeAll = [];
        meanMeanDeadTimeAllUpDown = [];
        %PT1
        meanPT1UpAll = [];
        meanPT1DownAll = [];
        meanPT1All = [];
        meanMeanPT1AllUpDown = [];
        %Schubkennlinie
        thrustVariablesArrayMeanAll = [];
        thrustVariablesArrayMeanMeanAll = [];
        thrustArrayStaticMeanAll = [];
        thrustArrayStaticMeanMeanAll = [];
        
        
    end


    methods (Access = public)
        
        function TimerCallback(app)
            
            %             app.Label.Text = datestr(now);
            
            
            myPlot(app);


            if strcmp(app.ModeSwitch.Value, app.ModeSwitch.Items{2})
                app.stepArrayCounter = app.stepArrayCounter + 1;
                myLength = length(app.stepArray);
                if app.stepArrayCounter > myLength
                    
                    %Reset To Manual
                    changeMode(app,1);
                    
                else
                    if app.stepArrayCounter >= 2
                        if app.stepArray(app.stepArrayCounter) ~= app.stepArray(app.stepArrayCounter-1)
                            setThrottle(app,app.stepArray(app.stepArrayCounter));
                        end
                    end
                    
                end
                
            end
            
            
        end


        function calc = calcThrottle8Bit(app,throttle_rel)
            calc = throttle_rel*app.throttleCalibrationMax/100;
            %Kalibration 0-252  (20.12.2017)
            if calc > app.throttleCalibrationMax
                calc = app.throttleCalibrationMax;
            end
            
        end
        
        function calc = calcThrottleRel(app,throttle_8bit)
            calc = throttle_8bit/app.throttleCalibrationMax*100;
            
        end


        function sendThrottle(app)
            
            calc = calcThrottle8Bit(app,app.setThrottleValue);
            fwrite(app.arduinoSerial_Send,calc,'async');
            fwrite(app.arduinoSerial_Receive,calc,'async');
            
        end
        
        function calcCurrentArduinoTime(app)
            app.arduinoTimestampCurrent = app.arduinoTimestampBegin + toc*1000000;
        end
        
        function createStepSignal(app)
            
            %First ascending steps
            app.stepArray=[];
            app.numberOfSteps=100/app.StepSizeEditField.Value;
            for i=1:(app.numberOfSteps+1)
                app.stepArray(1:1,(app.StepLengthEditField.Value*(i-1) +1):app.StepLengthEditField.Value*i)=app.StepSizeEditField.Value*(i-1);
            end
            
            %Descending steps
            for i=app.numberOfSteps+2:(app.numberOfSteps*2 +1 )
                app.stepArray(1:1,(app.StepLengthEditField.Value*(i-1) +1):app.StepLengthEditField.Value*i)=app.StepSizeEditField.Value*(app.numberOfSteps*2+1-i);
            end
            
            
            %            %-----
            %            app.numberOfSteps=100/app.stepSize;
            %            for i=1:(app.numberOfSteps+1)
            %                 app.stepArray(1:1,(app.stepLength*(i-1) +1):app.stepLength*i)=app.stepSize*(i-1);
            %            end
            %            %Descending steps
            %            for i=app.numberOfSteps+2:(app.numberOfSteps*2 +1 )
            %                 app.stepArray(1:1,(app.stepLength*(i-1) +1):app.stepLength*i)=app.stepSize*(app.numberOfSteps*2+1-i);
            %            end
            %            %----


            plot(app.UIAxes2,app.stepArray);
            
            
        end


        function readData(app)
            %Anzahl der Bytes im Buffer
            numOfBytesInBuffer = app.arduinoSerial_Receive.BytesAvailable;
            %Anzahl der Datensaetze im Buffer  Anzahl der Bytes in einem Datensatz = app.numOfBytes
            numOfDataInBuffer = fix(numOfBytesInBuffer/app.numOfBytes);
            %Auszulesende Anzahl in Buffer
            numOfBytesInBufferToRead = numOfDataInBuffer*app.numOfBytes;
            
            if numOfBytesInBufferToRead == 0
                return
            end
            
            % read and scale data from serial port
            app.received = fread(app.arduinoSerial_Receive, numOfBytesInBufferToRead, 'uint8')';
            
            isThrottleTimestampData = 0;
            isThrottleTimestampDataPredecessor = 0;
            numOfThrottleTimestampData = 0;
            
            %Iterate received Data
            for cn = 1:numOfDataInBuffer
                app.myNumber = cn*app.numOfBytes;
                
                %Current position in savedData
                app.currentPos = app.currentPositionInRecArr + cn - 1;
                % app.currentPos = app.currentPos + cn - 1;
                
                %oneDataset
                app.receivedData = app.received(1,(app.myNumber-app.numOfBytes+1):app.myNumber);
                
                %check if received Data is throttle Set timestamp
                checkBytesThrottle = [255 255 255];
                
                checkByte = [0 0 0];
                checkByte(1,1) =  typecast(uint8(app.receivedData(1,5)), 'uint8');
                checkByte(1,2) =   typecast(uint8(app.receivedData(1,6)), 'uint8');
                checkByte(1,3) =   typecast(uint8(app.receivedData(1,7)), 'uint8');
                
                if  checkByte == checkBytesThrottle
                    isThrottleTimestampData = 1;
                    numOfThrottleTimestampData = numOfThrottleTimestampData + 1;
                    
                end
                
                %Save Raw Data
                app.rawData(app.currentPos,:) =  app.receivedData;
                
                
                %Save Timestamp
                app.savedData((app.currentPos),1) = typecast(uint8(app.receivedData(1,1:4)), 'uint32');
                
                if isThrottleTimestampData == 1
                    %-------Stellgrößenempfang ---------
                    %Save current throttle
                    app.savedData((app.currentPos),2) =  typecast(uint8(app.receivedData(1,8)), 'uint8');
                    
                    %copy  Speed
                    app.savedData((app.currentPos),3) =  255;
                    
                    %copy weight
                    app.savedData((app.currentPos),4) =  255;
                    
                    %copy current
                    app.savedData((app.currentPos),5) = 255;
                    
%                     isThrottleTimestampDataPredecessor = 1;
                    isThrottleTimestampData = 0
                    
                else
                    %OLD--------------
%                     if isThrottleTimestampDataPredecessor == 0
%                         % -------Normaler Datensatz mit normalem Vorgänger------
%                         
%                         
%                         %Save/Calculate Speed
%                         app.savedData((app.currentPos),3) = 60000000/(app.savedData((app.currentPos),1)-app.savedData((app.currentPos-1),1));
%                         
%                     else
%                         % ------Normaler Datensatz mit Vorgänger: Stellgröße------
%                         %Save/Calculate Speed
%                         app.savedData((app.currentPos),3) = 60000000/(app.savedData((app.currentPos),1)-app.savedData((app.currentPos-2),1));
%                         
%                     end
                      %OLD---------------
                    
                     %Save/Calculate Speed
                      app.savedData((app.currentPos),3) = 60000000/(app.savedData((app.currentPos),1)-app.savedData((app.currentPos-1),1));


                    %Save current throttle
                    app.savedData((app.currentPos),2) =  app.savedData(app.currentPos-1,2);
                    
                    %Save weight
                    app.savedData((app.currentPos),4) = (typecast(uint8(app.receivedData(1,5:6)), 'uint16'))/10;
                    
                    %Save current
                    app.savedData((app.currentPos),5) = typecast(uint8(app.receivedData(1,7:8)), 'uint16');
                    
%                     isThrottleTimestampDataPredecessor = 0;
                end
            end


            %             %New Position in Array
            app.currentPositionInRecArr =  app.currentPositionInRecArr + numOfDataInBuffer;
            %
            
            %Counter
            app.posCounter = app.posCounter +1;
            
            
            %             %Set Curr Throttle
            myCurrThrottle = calcThrottleRel(app,app.savedData((app.currentPos),2));
            app.CurrthrottleEditField.Value = myCurrThrottle;


        end


        function plotThrottle(app)
            curr_Position_in_Array = app.currentPositionInRecArr-1;
            myfig = figure;
            title(gca,'Stellgröße und Drehzahl');
            plot(gca,app.savedData(:,1),app.savedData(:,2)*50);
            hold on
            plot(gca,app.savedData(:,1),app.savedData(:,3));
            %             myfig = figure;
            %             title('Drehzahl');
            plot(gca,app.savedData(:,1),app.savedData(:,3));
            % plot(myfig,app.savedData(:,2))
            title(gca,'Stellgröße und Drehzahl');
            
            myfig = figure;
            
            plot(gca,app.savedData(:,1),app.savedData(:,4));
            title(gca,'Schub');
            
            
            myfig = figure;
            
            plot(gca,app.savedData(:,1),app.savedData(:,5));
            title(gca,'Strom');
            
            
        end
        
        
        %Final conversion for the measurement
        function convertMeasurement(app)


        end
        
        function currentFilter(app)
            
            t = app.savedData(:,1);
            x = app.savedData(:,5);
            
            scaled = [];
            stepSize = .1;
            i = 0;
            n = 0;
            sum = 0;
            for j = 1:length(t)
                
                if ( (t(j) >= i) && (t(j) < i + stepSize) )
                    sum = sum + x(j);
                    n = n + 1;
                else
                    % Durchschnitt bilden
                    average = sum / n;
                    scaled = [scaled;i average];
                    sum = x(j);
                    n = 1;
                    i = i + stepSize;
                end
            end
            % Letzten Wert berechnen
            average = sum / n;
            scaled = [scaled;i average];
            
            %plot(scaled(:,1),scaled(:,2));
            
            
            % Filter
            lpFilter = designfilt('lowpassiir', 'PassbandFrequency', 1, 'StopbandFrequency', 2, 'PassbandRipple', 1, 'StopbandAttenuation', 60, 'SampleRate', 10);
            
            filtered = filter(lpFilter, scaled);
            % hold on;
            %plot(filtered(:,1),filtered(:,2));
            assignin('base','filteredCurrent',filtered);
        end
        
    end
    
    methods (Access = private)
        
        function setThrottle(app,newThrottleValue)
            app.previousThrottleValue = app.setThrottleValue;
            app.setThrottleValue = newThrottleValue;
            app.ThrottleSlider.Value = newThrottleValue;
            app.ThrottleSetEditField.Value = newThrottleValue;
            sendThrottle(app);
            calcCurrentArduinoTime(app);
            
        end
        
        function myPlot(app)
            if isempty(app.receivedData) == 0
                if app.currentPositionInRecArr > app.displayLength
                    %                     plot(app.UIAxes,app.receivedData((end-100):end,1));
                    
                    %set(app.myLine,'YData',app.receivedData((end-app.displayLength):end,1));
                    set(app.myLine,'YData',app.savedData((app.currentPositionInRecArr-app.displayLength):app.currentPositionInRecArr,3));
                else
                    app.myLine = plot(app.UIAxes,app.savedData(1:app.currentPositionInRecArr,3));
                    %app.myLine = plot(app.UIAxes,app.time);


                end
            end
        end
        
        function force = calculateForceFromGramm(app,gramm)
            force = gramm/1000*9.81;
            
        end
        
        function includeThrottleInData(app)
            LineBeginThrottle = 0;
            savedDataFINAL = sortrows(app.savedData);
            %Search Throttle Timestamp begin
            checkBytesThrottle = [255 255 255];
            for i=2:size(savedDataFINAL,1)
                checkBytesThrottle = [255 255 255];
                if (savedDataFINAL(i,3:5) == checkBytesThrottle)
                     %Copy from previous
                     savedDataFINAL(i,3:5) = savedDataFINAL(i-1,3:5);
                     
                     %Calc Throttle
                     throttleRel = calcThrottleRel(app,savedDataFINAL(i,2));
                       savedDataFINAL(i,2) = throttleRel;
                else
                    
                       %Copy throttle and calculate
                      savedDataFINAL(i,2) = savedDataFINAL(i-1,2);
                     
                end
               %Calculate in Force
%                 savedDataFINAL(i,4) = calculateForceFromGramm(app,savedDataFINAL(i,4));
             
            end
            %Calculate in Force
            savedDataFINAL(:,4) = savedDataFINAL(:,4)/1000*9.81;
            
            %Cut last Data
            for i=size(savedDataFINAL,1):-1:1
                if savedDataFINAL(i,2) ~= 0
                     %Copy from previous
                     savedDataFINAL = savedDataFINAL(1:i,:);
                     break
                end
            end


            app.savedData = savedDataFINAL;
        end
        
        function setStandBy(app)
            stop(app.myTimer);


            %Receive Stellgroesse
            fwrite(app.arduinoSerial_Receive,255,'async');
            
            %Wait for Stellgroesse
            pause(0.2);
            
            %Read Rest Data
            readData(app);
            
            %Cut zeros
            newArray = app.savedData(1:app.currentPositionInRecArr-1,:);
            app.savedData =newArray;
            
            %Include Throttle in read Data
            includeThrottleInData(app);
            
            
            %micros to s
            app.savedData(:,1) = app.savedData(:,1)/1000000;
            app.savedData(2:end,1) = app.savedData(2:end,1)-app.savedData(2,1);
            currentFilter(app);
            recordData = app.savedData;
            nameOfRecordedDataset = 'recordData';


            %Save in Workspace
            assignin('base',nameOfRecordedDataset,app.savedData);


            %Save in Directory
            mkdir(app.directoryMeasurementName);
            currentTime = datestr(now);
            %Replace : with _
            replace=strfind(currentTime, ':') ;
            for repInd = 1:length(replace)
                currentTime(replace(repInd)) = '_';
            end
            
            %Replace ' ' with _
            replace=strfind(currentTime, ' ') ;
            for repInd = 1:length(replace)
                currentTime(replace(repInd)) = '_';
            end
            
            savePath = [app.directoryMeasurementName '/' currentTime '.mat'];
            save(savePath, nameOfRecordedDataset);
            
            %Save Raw Data
            assignin('base','rawData',app.rawData);
            app.rawData = zeros(10000000,8);
            
            
            %             closeSendPort(app);
            %             closeReceivePort(app);
            %             instrreset
            %
            %             checkAvailableConnections(app);
            
            %             delete(app.myTimer);


        end
        
        function init(app)
            app.savedData = zeros(10000000,5);
            app.currentPositionInRecArr = 2;
            app.posCounter = 2;
        end


        function setRecord(app)
            
            %Clear serial input buffer
            flushinput(app.arduinoSerial_Receive);
            
            %Init Arrays
            init(app);
            
            %Start Timer
            start(app.myTimer);


        end
        
        function checkIfAllConnected(app)
            if app.ConnectButton.Value == 1 && app.ConnectButton_2.Value == 1
                app.StartStopButton.Enable = 'on';
            else
                app.StartStopButton.Enable = 'off';
            end
        end
        
        function connectSerialReceive(app)


            %define serial
            app.arduinoSerial_Receive = serial(app.PortDropDown_2.Value,'BaudRate',app.baud);


            % define call funcion
            app.arduinoSerial_Receive.BytesAvailableFcnCount = app.bufferBytes;
            app.arduinoSerial_Receive.BytesAvailableFcnMode = 'byte';
            app.arduinoSerial_Receive.BytesAvailableFcn = @(~, ~)app.readData;


            % open port
            fopen(app.arduinoSerial_Receive);


            %check if port is opened
            if strcmp(convertCharsToStrings(app.arduinoSerial_Receive.Status), 'open')
                app.Lamp_3.Color = 'green';
                
                
            else
                app.Lamp_3.Color = 'red';
                app.ConnectButton_2.Value = 0;
            end
            checkIfAllConnected(app);
        end
        
        
        function connectSerialSend(app)
            %define serial
            app.arduinoSerial_Send = serial(app.PortDropDown.Value,'BaudRate',app.baud);
            
            % open port
            fopen(app.arduinoSerial_Send);
            
            %check if port is opened
            if strcmp(convertCharsToStrings(app.arduinoSerial_Send.Status), 'open')
                app.Lamp_2.Color = 'green';
                
            else
                app.Lamp_2.Color = 'red';
                app.ConnectButton.Value = 0;
            end
            checkIfAllConnected(app);
        end
        
        function checkAvailableConnections(app)
            p = instrhwinfo('serial');
            app.PortDropDown.Items = p.AvailableSerialPorts;
            app.PortDropDown_2.Items = p.AvailableSerialPorts;
        end
        
        function closeSendPort(app)
            if ~isempty(app.arduinoSerial_Send)
                stopasync(app.arduinoSerial_Send);
                fclose(app.arduinoSerial_Send);
            end
            
            app.Lamp_2.Color = 'red';
            app.ConnectButton.Value = 0;
            checkIfAllConnected(app);
        end
        
        function closeReceivePort(app)
            
            stopasync(app.arduinoSerial_Receive);
            fclose(app.arduinoSerial_Receive);
            checkIfAllConnected(app);
            app.Lamp_3.Color = 'red';
            app.ConnectButton_2.Value = 0;
        end
        
        
        function changeMode(app,modeNumber)
            app.ModeSwitch.Value = app.ModeSwitch.Items{modeNumber};
            if modeNumber == 2
                app.stepArrayCounter = 0;
                app.ThrottleSlider.Enable = 'off';
                app.ThrottleSetEditField.Enable = 'off';
                
            else
                %Manual
                setThrottle(app,0);
                app.stepArrayCounter = 0;
                app.ThrottleSlider.Enable = 'on';
                app.ThrottleSetEditField.Enable = 'on';
            end
            
        end
        
        %-----------------Tab Identification---------------
        
        function clearIdentificationData(app)
            
            %Identification
            %Speed
            app.speedArrayStaticMeanAll = [];
            app.speedArrayStaticMeanMeanAll = [];
            app.throttleArrayStaticMeanAll = [];
            %Totzeit
            app.meanDeadTimeUpAll =[];
            app.meanDeadTimeDownAll = [];
            app.meanDeadTimeAll = [];
            app.meanMeanDeadTimeAllUpDown = [];
            %PT1
            app.meanPT1UpAll = [];
            app.meanPT1DownAll = [];
            app.meanPT1All = [];
            app.meanMeanPT1AllUpDown = [];
            %Schubkennlinie
            app.thrustVariablesArrayMeanAll = [];
            app.thrustVariablesArrayMeanMeanAll = [];
            app.thrustArrayStaticMeanAll = [];
            app.thrustArrayStaticMeanMeanAll = [];
        end
        
        
        function saveIdentificationToWorkspace(app)
            
            assignin('base','speedArrayStaticMeanAll',app.speedArrayStaticMeanAll);
            assignin('base','throttleArrayStaticMeanAll',app.throttleArrayStaticMeanAll);
            
            assignin('base','meanDeadTimeUpAll',app.meanDeadTimeUpAll);
            assignin('base','meanDeadTimeDownAll',app.meanDeadTimeDownAll);
            assignin('base','meanDeadTimeAll',app.meanDeadTimeAll);
            
            assignin('base','meanPT1UpAll',app.meanPT1UpAll);
            assignin('base','meanPT1DownAll',app.meanPT1DownAll);
            assignin('base','meanPT1All',app.meanPT1All);
            
            assignin('base','thrustVariablesArrayMeanAll',app.thrustVariablesArrayMeanAll);
            assignin('base','thrustVariablesArrayMeanMeanAll',app.thrustVariablesArrayMeanMeanAll);
            assignin('base','thrustArrayStaticMean',app.thrustArrayStaticMeanAll);
            
        end


        function readAndDisplayMeasurementDirectory(app)
            
            
            app.measureFolder = dir('Messungen');
            
            counter = 0;
            for k=1:size(app.measureFolder,1)
                if app.measureFolder(k).isdir == 0
                    
                    counter = counter + 1;
                    inFiles{counter}=app.measureFolder(k).name;
                end
            end
            
            app.ListBox.Items = inFiles;


        end
        
        
        function identificateSelectedData(app)
            %Clear Data
            clearIdentificationData(app);
            clearPlot(app);
            
            directoryCurrRecordStruc = app.ListBox.Value;
            for i=1:length(directoryCurrRecordStruc)
                currentDataName = directoryCurrRecordStruc{i};
                identificationOneData(app,currentDataName);
                
                
            end
            
            %assignin('base','speedArrayStaticMeanAll',app.speedArrayStaticMeanAll);
            saveIdentificationToWorkspace(app);
            
            calculateDrehzahlkennlinie(app);
            calculateMeanMeanDeadTimeUpDown(app);
            calculatePT1MeanUpDown(app);
            calculateSchubkennlinie(app);
            
            plotIdentification(app);
            
            
        end
        
        function clearPlot(app)
            cla(app.UIAxes3);
        end
        
        function calculateDrehzahlkennlinie(app)
            numberSize = size(app.speedArrayStaticMeanAll,1);
            for i=1:numberSize
                app.speedArrayStaticMeanMeanAll(i,1) = mean(app.speedArrayStaticMeanAll(i,:));
            end


        end
        
        function calculateMeanMeanDeadTimeUpDown(app)
            app.meanMeanDeadTimeAllUpDown(1,1) = mean(app.meanDeadTimeUpAll);
            app.meanMeanDeadTimeAllUpDown(1,2) = mean(app.meanDeadTimeDownAll);
        end
        
        function calculatePT1MeanUpDown(app)
            app.meanMeanPT1AllUpDown(1,1) = mean(app.meanPT1UpAll);
            app.meanMeanPT1AllUpDown(1,2) = mean(app.meanPT1DownAll);
        end
        
        function calculateSchubkennlinie(app)
            numberSize = size(app.thrustArrayStaticMeanAll,1);
            for i=1:numberSize
                app.thrustArrayStaticMeanMeanAll(i,1) = mean(app.thrustArrayStaticMeanAll(i,:));
            end
            
            for i=1:3
                app.thrustVariablesArrayMeanMeanAll(i,1) = mean(app.thrustVariablesArrayMeanAll(i,:));
            end


        end
        
        function plotIdentification(app)
            
            %Drehzahlkennlinie
            %
            throttleArrayStaticMean = app.throttleArrayStaticMeanAll(:,1);
            SpeedArrayStaticMean = app.speedArrayStaticMeanMeanAll;
            
            
            %             assignin('base','throttleArrayStaticMean',throttleArrayStaticMean);
            %             assignin('base','SpeedArrayStaticMean',SpeedArrayStaticMean);
            
            extrapolatedSpeed = getSpeed_from_Throttle(0:1:252);
            plot(app.UIAxesSpeedThrottle,0:1:252,extrapolatedSpeed);
            
            %Totzeit
            
            app.DeadtimeEditField.Value = mean(app.meanMeanDeadTimeAllUpDown)*1000;
            
            %PT1
            myMeanPT1 = mean(app.meanPT1All);
            fplot (app.UIAxesPT1,@(t) ((1-exp(-t/myMeanPT1))),[0 0.5]);
            app.TauEditField.Value = myMeanPT1*1000;
            
            %Schubkennlinie
            myMeanSchub = app.thrustVariablesArrayMeanMeanAll;
            
            fplot (app.UIAxesSchub,@(omega) ((myMeanSchub(3)*omega.^2)+(myMeanSchub(2)*omega) + myMeanSchub(1)),[0 10000]);
            stringSchubFormel = [num2str(myMeanSchub(3)) 'ω' char(178) ' + ' num2str(myMeanSchub(2)) '*ω + ' num2str(myMeanSchub(1)) ];
            
            app.ThrustCurveLabel.Text = stringSchubFormel;


        end
        
        function identificationOneData(app,directoryData)
            
            % directoryCurrRecord = app.ListBox.Value;
            directory =  getCurrentDataDirectory(app,directoryData);
            
            recordDataStruc = load(directory);
            recordData = recordDataStruc.recordData;
            assignin('base','currentRecordData',recordData);
            run('DatenAuswerten.m');


            %append data
            app.speedArrayStaticMeanAll = [app.speedArrayStaticMeanAll,SpeedArrayStaticMean];
            app.throttleArrayStaticMeanAll = [app.throttleArrayStaticMeanAll,throttleArrayStaticMean];
            
            
            app.meanDeadTimeUpAll = [app.meanDeadTimeUpAll,meanDeadTimeUp];
            app.meanDeadTimeDownAll = [app.meanDeadTimeDownAll,meanDeadTimeDown];
            app.meanDeadTimeAll = [app.meanDeadTimeAll,meanDeadTime];
            
            app.meanPT1UpAll = [app.meanPT1UpAll,meanPT1Up];
            app.meanPT1DownAll = [app.meanPT1DownAll,meanPT1Down];
            app.meanPT1All = [app.meanPT1All,meanPT1];
            
            app.thrustArrayStaticMeanAll = [app.thrustArrayStaticMeanAll,thrustArrayStaticMean];
            app.thrustVariablesArrayMeanAll = [app.thrustVariablesArrayMeanAll,thrustVariablesArray];
            %Plot Data
            
            plot(app.UIAxes3,time,motorSpeed)
            
            hold(app.UIAxes3,'on');
            
            mySpeed = getSpeed_from_Throttle(motorPowerSet);
            plot(app.UIAxes3,time,mySpeed);
            
            
        end
        
        function [directory] = getCurrentDataDirectory(app,measureName)
            
            directory =  [app.directoryMeasurementName '/' measureName];
            
        end


    end
    

    methods (Access = private)

        % Code that executes after component creation
        function SetupSystem(app)
            %Calc Buffer length
            app.bufferBytes = app.numOfBytes*app.bufferLength;
            
            %Timer setup
            app.myTimer = timer('StartDelay', 0, 'Period', 0.1,'ExecutionMode', 'fixedRate','BusyMode','drop');
            app.myTimer.TimerFcn = @(~, ~) app.TimerCallback;
            
            %Check avalable Serial Ports and display
            checkAvailableConnections(app);
            
            %Satus not ready
            app.StartStopButton.Enable = 'off';
            
            app.StepLengthEditField.Value = 10;
            app.StepSizeEditField.Value = 10;
            
            createStepSignal(app);


        end

        % Callback function
        function InputSliderValueChanged(app, event)
            value = app.InputSlider.Value;
            plot(app.UIAxes, value*peaks)
            app.UIAxes.YLim = [-1000 1000];
        end

        % Value changed function: ThrottleSlider
        function ThrottleKnobValueChanging(app, event)
            changingValue = event.Value;
            
            setThrottle(app, changingValue);
            %             fwrite(arduino,changingValue);
            
            
        end

        % Value changed function: ThrottleSetEditField
        function ThrottleSetEditFieldValueChanged(app, event)
            value = app.ThrottleSetEditField.Value;
            setThrottle(app, value)
            %             fwrite(arduino,changingValue);
        end

        % Value changed function: StartStopButton
        function StartStopButtonValueChanged(app, event)
            value = app.StartStopButton.Value;
            
            
            if value == 1
                %Running /Start
                
                
                %Receive Data
                setRecord(app);
                app.Lamp.Color = 'green';
                
            else
                %Stop
                setStandBy(app);
                app.Lamp.Color = 'red';


            end
            
            
        end

        % Close request function: UIFigure
        function CloseSystem(app, event)
            
            
            %             Serial_Close
            %             pause(0.01);
            
            %             fclose(app.arduinoSerial);
            %             delete(app.arduinoSerial)
            
            %setStandBy(app);
            pause(1);
            delete(app.myTimer);
            %
            %             pause(0.01);
            delete(app)


        end

        % Button pushed function: PlotButton
        function PlotButtonPushed(app, event)
            plotThrottle(app);
        end

        % Value changed function: ConnectButton
        function ConnectButtonValueChanged(app, event)
            value = app.ConnectButton.Value;
            
            if value == 1
                connectSerialSend(app);
            else
                closeSendPort(app);
                
                
            end
        end

        % Value changed function: ConnectButton_2
        function ConnectButton_2ValueChanged(app, event)
            value = app.ConnectButton_2.Value;
            
            if value == 1
                connectSerialReceive(app);
            else
                closeReceivePort(app);
                
            end
        end

        % Button pushed function: ClearConnectionsButton
        function ClearConnectionsButtonPushed(app, event)
            closeSendPort(app);
            closeReceivePort(app);
            instrreset
            
            checkAvailableConnections(app);
            
        end

        % Value changed function: ModeSwitch
        function ModeSwitchValueChanged(app, event)
            value = app.ModeSwitch.Value;
            
            if strcmp(value, app.ModeSwitch.Items{2})
                changeMode(app,2);
                
            else
                changeMode(app,1);
                
            end


        end

        % Value changed function: StepLengthEditField, 
        % StepSizeEditField
        function StepLengthEditFieldValueChanged(app, event)
            createStepSignal(app);
            
        end

        % Selection change function: TabGroup
        function TabGroupSelectionChanged(app, event)
            selectedTab = app.TabGroup.SelectedTab;
            selectedTab.Title;
            if strcmp(selectedTab.Title,'Identification')
                readAndDisplayMeasurementDirectory(app);
                
            end
        end

        % Button pushed function: IdentificationButton
        function IdentificationButtonPushed(app, event)
            %              dataStruc = app.ListBox.Value;
            %              identificationOneData(app,dataStruc{1});
            
            identificateSelectedData(app);
            
            
        end
    end

    % App initialization and construction
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure;
            app.UIFigure.Name = 'UI Figure';
            app.UIFigure.CloseRequestFcn = createCallbackFcn(app, @CloseSystem, true);
            app.TabGroup = uitabgroup(app.UIFigure);
            app.TabGroup.SelectionChangedFcn = createCallbackFcn(app, @TabGroupSelectionChanged, true);
            app.MeasuringTab = uitab(app.TabGroup);
            app.MeasuringTab.Title = 'Measuring';
            app.StartStopButton = uibutton(app.MeasuringTab, 'state');
            app.StartStopButton.ValueChangedFcn = createCallbackFcn(app, @StartStopButtonValueChanged, true);
            app.StartStopButton.Text = 'Start/Stop';
            app.PlotButton = uibutton(app.MeasuringTab, 'push');
            app.PlotButton.ButtonPushedFcn = createCallbackFcn(app, @PlotButtonPushed, true);
            app.PlotButton.Text = 'Plot';
            app.TimestampLabel = uilabel(app.MeasuringTab);
            app.TimestampLabel.Text = 'Timestamp:';
            app.Received_timestampLabel = uilabel(app.MeasuringTab);
            app.Received_timestampLabel.Text = 'Received_timestamp';
            app.SpeedLabel = uilabel(app.MeasuringTab);
            app.SpeedLabel.Text = 'Speed:';
            app.received_speedLabel = uilabel(app.MeasuringTab);
            app.received_speedLabel.Text = 'received_speed';
            app.ConnectionsPanel = uipanel(app.MeasuringTab);
            app.ConnectionsPanel.Title = 'Connections';
            app.SendPortPanel = uipanel(app.ConnectionsPanel);
            app.SendPortPanel.Title = 'Send Port';
            app.PortDropDownLabel = uilabel(app.SendPortPanel);
            app.PortDropDownLabel.Text = ' Port';
            app.PortDropDown = uidropdown(app.SendPortPanel);
            app.ConnectButton = uibutton(app.SendPortPanel, 'state');
            app.ConnectButton.ValueChangedFcn = createCallbackFcn(app, @ConnectButtonValueChanged, true);
            app.ConnectButton.Text = 'Connect';
            app.ReceivePortPanel = uipanel(app.ConnectionsPanel);
            app.ReceivePortPanel.Title = 'Receive Port';
            app.PortDropDown_2Label = uilabel(app.ReceivePortPanel);
            app.PortDropDown_2Label.Text = ' Port';
            app.PortDropDown_2 = uidropdown(app.ReceivePortPanel);
            app.ConnectButton_2 = uibutton(app.ReceivePortPanel, 'state');
            app.ConnectButton_2.ValueChangedFcn = createCallbackFcn(app, @ConnectButton_2ValueChanged, true);
            app.ConnectButton_2.Text = 'Connect';
            app.ClearConnectionsButton = uibutton(app.ConnectionsPanel, 'push');
            app.ClearConnectionsButton.ButtonPushedFcn = createCallbackFcn(app, @ClearConnectionsButtonPushed, true);
            app.ClearConnectionsButton.Text = 'Clear Connections';
            app.Lamp_3 = uilamp(app.ConnectionsPanel);
            app.Lamp_2 = uilamp(app.ConnectionsPanel);
            app.Lamp = uilamp(app.MeasuringTab);
            app.ModeSwitch = uiswitch(app.MeasuringTab, 'slider');
            app.ModeSwitch.Items = {'Manual Control', 'Step Signal'};
            app.ModeSwitch.ValueChangedFcn = createCallbackFcn(app, @ModeSwitchValueChanged, true);
            app.ModeSwitch.Value = 'Manual Control';
            app.StepSignalPanel = uipanel(app.MeasuringTab);
            app.StepSignalPanel.Title = 'Step Signal';
            app.LabelNumericEditField3 = uilabel(app.StepSignalPanel);
            app.LabelNumericEditField3.Text = 'Length';
            app.StepLengthEditField = uieditfield(app.StepSignalPanel, 'numeric');
            app.StepLengthEditField.ValueChangedFcn = createCallbackFcn(app, @StepLengthEditFieldValueChanged, true);
            app.StepSizeEditFieldLabel = uilabel(app.StepSignalPanel);
            app.StepSizeEditFieldLabel.Text = 'Step Size';
            app.StepSizeEditField = uieditfield(app.StepSignalPanel, 'numeric');
            app.StepSizeEditField.ValueChangedFcn = createCallbackFcn(app, @StepLengthEditFieldValueChanged, true);
            app.UIAxes = uiaxes(app.MeasuringTab);
            title(app.UIAxes, 'Real Time Motorspeed')
            xlabel(app.UIAxes, 'Time in s')
            ylabel(app.UIAxes, 'Motorspeed in RPM')
            app.UIAxes2 = uiaxes(app.MeasuringTab);
            title(app.UIAxes2, 'Step Signal')
            xlabel(app.UIAxes2, 'Time in s')
            ylabel(app.UIAxes2, 'Throttle in %')
            app.ThrottleSetEditFieldLabel = uilabel(app.MeasuringTab);
            app.ThrottleSetEditFieldLabel.Text = ' Throttle Set:';
            app.ThrottleSetEditField = uieditfield(app.MeasuringTab, 'numeric');
            app.ThrottleSetEditField.Limits = [0 100];
            app.ThrottleSetEditField.ValueDisplayFormat = '%.2f';
            app.ThrottleSetEditField.ValueChangedFcn = createCallbackFcn(app, @ThrottleSetEditFieldValueChanged, true);
            app.CurrthrottleEditFieldLabel = uilabel(app.MeasuringTab);
            app.CurrthrottleEditFieldLabel.Text = 'Curr. throttle';
            app.CurrthrottleEditField = uieditfield(app.MeasuringTab, 'numeric');
            app.CurrthrottleEditField.Limits = [0 100];
            app.CurrthrottleEditField.ValueDisplayFormat = '%.2f';
            app.CurrthrottleEditField.Editable = 'off';
            app.ThrottleSliderLabel = uilabel(app.MeasuringTab);
            app.ThrottleSliderLabel.Text = 'Throttle';
            app.ThrottleSlider = uislider(app.MeasuringTab);
            app.ThrottleSlider.ValueChangedFcn = createCallbackFcn(app, @ThrottleKnobValueChanging, true);
            app.IdentificationTab = uitab(app.TabGroup);
            app.IdentificationTab.Title = 'Identification';
            app.ListBoxLabel = uilabel(app.IdentificationTab);
            app.ListBoxLabel.Text = '';
            app.ListBox = uilistbox(app.IdentificationTab);
            app.ListBox.Multiselect = 'on';
            app.ListBox.Value = {'Item 1'};
            app.UIAxes3 = uiaxes(app.IdentificationTab);
            title(app.UIAxes3, 'Motorspeed')
            xlabel(app.UIAxes3, 'Time in s')
            ylabel(app.UIAxes3, 'Speed in RPM')
            app.IdentificationButton = uibutton(app.IdentificationTab, 'push');
            app.IdentificationButton.ButtonPushedFcn = createCallbackFcn(app, @IdentificationButtonPushed, true);
            app.IdentificationButton.Text = 'Identification';
            app.UIAxesSpeedThrottle = uiaxes(app.IdentificationTab);
            title(app.UIAxesSpeedThrottle, 'Speed-Throttle Curve')
            xlabel(app.UIAxesSpeedThrottle, 'Throttle in %')
            ylabel(app.UIAxesSpeedThrottle, 'Speed in RPM')
            app.UIAxesPT1 = uiaxes(app.IdentificationTab);
            title(app.UIAxesPT1, 'PT1')
            xlabel(app.UIAxesPT1, 'Time in s')
            ylabel(app.UIAxesPT1, 'Speed')
            app.UIAxesSchub = uiaxes(app.IdentificationTab);
            title(app.UIAxesSchub, 'Thrust-Speed-Curve')
            xlabel(app.UIAxesSchub, 'Speed in RPM')
            ylabel(app.UIAxesSchub, 'Thrust in g')
            app.Label = uilabel(app.IdentificationTab);
            app.Label.Text = '→';
            app.Label_2 = uilabel(app.IdentificationTab);
            app.Label_2.Text = '→';
            app.DeadtimeLabel = uilabel(app.IdentificationTab);
            app.DeadtimeLabel.Text = 'Deadtime =';
            app.DeadtimeEditField = uieditfield(app.IdentificationTab, 'numeric');
            app.DeadtimeEditField.ValueDisplayFormat = '%.3f';
            app.DeadtimeEditField.Editable = 'off';
            app.msLabel = uilabel(app.IdentificationTab);
            app.msLabel.Text = 'ms';
            app.TauLabel = uilabel(app.IdentificationTab);
            app.TauLabel.Text = 'Tau =';
            app.TauEditField = uieditfield(app.IdentificationTab, 'numeric');
            app.TauEditField.ValueDisplayFormat = '%.3f';
            app.TauEditField.Editable = 'off';
            app.msLabel_2 = uilabel(app.IdentificationTab);
            app.msLabel_2.Text = 'ms';
            app.ThrustCurveLabel = uilabel(app.IdentificationTab);
            app.ThrustCurveLabel.Text = 'ThrustCurve';
        end
    end

    methods (Access = public)

        % Construct app
        function app = controlApp

            % Create and configure components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @SetupSystem)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end